classdef STORMprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ExportButton                   matlab.ui.control.Button
        RenderButton                   matlab.ui.control.Button
        DrawinthisviewButton           matlab.ui.control.Button
        SaveDisButton                  matlab.ui.control.Button
        OutputdistanceButton           matlab.ui.control.Button
        PeakcountSpinner               matlab.ui.control.Spinner
        PeakcountSpinnerLabel          matlab.ui.control.Label
        SmoothspanSlider               matlab.ui.control.Slider
        SmoothspanLabel                matlab.ui.control.Label
        angelSpinner                   matlab.ui.control.Spinner
        angelSpinnerLabel              matlab.ui.control.Label
        FitaxialButton                 matlab.ui.control.Button
        CheckSelectedButton            matlab.ui.control.Button
        NoofdisplayedpointsEditField   matlab.ui.control.NumericEditField
        NoofdisplayedpointsEditFieldLabel  matlab.ui.control.Label
        InfiniteCheckBox_2             matlab.ui.control.CheckBox
        InfiniteCheckBox               matlab.ui.control.CheckBox
        CheckthisButton                matlab.ui.control.Button
        DensityThresholdSlider_2       matlab.ui.control.Slider
        DensityThresholdSlider_2Label  matlab.ui.control.Label
        UseautoResultsButton           matlab.ui.control.Button
        AutoSelectButton               matlab.ui.control.Button
        DensityThresholdSlider         matlab.ui.control.Slider
        DensityThresholdSliderLabel    matlab.ui.control.Label
        LoadLastButton                 matlab.ui.control.Button
        SelectinthisviewButton         matlab.ui.control.Button
        MsgEditField                   matlab.ui.control.EditField
        MsgLabel                       matlab.ui.control.Label
        ToS                            matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        NoofSynapseSpinner             matlab.ui.control.Spinner
        NoofSynapseSpinnerLabel        matlab.ui.control.Label
        SelectClusterButton            matlab.ui.control.Button
        DeleteLastButton               matlab.ui.control.Button
        SelectSynapseButton            matlab.ui.control.Button
        FinishselectButton             matlab.ui.control.Button
        LoadIamgeButton                matlab.ui.control.Button
        UIAxes_XY                      matlab.ui.control.UIAxes
        UIAxes_SV                      matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xt % Description
        yt % Description
        dd=1000*67.75/700; %pixel size
        lx % size
        ly % size
        fname % file number
        X1
        Y1
        Z1
        X2
        Y2
        Z2
        NoS=0; %No. of Synapse
        Synapse=struct('coord1',[],'coord2',[],'Vol11',[],'Vol21',[],'ID1',[],'ID2',[]); %Synapse information
        mVol1
        mVol2
        Vol11
        Vol21
        NoSP=0;
        pkds
        pkds1
        pkds2
    end
    
    properties (Access = public)
        Trackselect % Description
    end
    
    methods (Access = private)
        
        function plot4Synapse(app,x1t,y1t,z1t,x2t,y2t,z2t)
            plot(x1t,y1t,'g.',x2t,y2t,'r.','MarkerSize',10,'Parent',app.UIAxes_XY)
            app.UIAxes_XY.XLim=[min([x1t;x2t])-100 max([x1t;x2t])+100];
            app.UIAxes_XY.YLim=[min([y1t;y2t])-100 max([y1t;y2t])+100];
            plot(x1t,z1t,'g.',x2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_XZ)
            app.UIAxes_XZ.XLim=[min([x1t;x2t])-100 max([x1t;x2t])+100];
            app.UIAxes_XZ.YLim=[min([z1t;z2t])-100 max([z1t;z2t])+100];
            plot(y1t,z1t,'g.',y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_YZ)
            app.UIAxes_YZ.YLim=[min([z1t;z2t])-100 max([z1t;z2t])+100];
            app.UIAxes_YZ.XLim=[min([y1t;y2t])-100 max([y1t;y2t])+100];
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            xyz=app.Synapse(app.NoS).coord2;
            coeff=pca(xyz);
            view(app.UIAxes_SV,coeff(:,2));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIamgeButton
        function loaddata(app, event)
            app.NoS=0;
            app.ToS.Value=app.NoS;
            app.MsgEditField.Value='Loading WF and SR data...';
            [filename,pathname]=uigetfile('*.*','Select an Image');
            app.fname=filename(1);
            filename=strcat(pathname,filename);
            I=imread(filename);
            [app.ly app.lx lz]=size(I);
            assignin('base','ly',app.ly);
            ax1=app.UIAxes;
            imshow(I,'Parent',app.UIAxes)
            
            app.MsgEditField.Value='Loading FOid data...';
            load([app.fname,'-FOid.mat']);
            app.mVol1=mVol1;app.mVol2=mVol2;
            app.Vol11=Vol11;app.Vol21=Vol21;
%             data=importdata([app.fname,'-SR-568.txt']);
            app.X1=X1;
            app.Y1=Y1;
            app.Z1=Z1;
%             data=importdata([app.fname,'-SR-647.txt']);
            app.X2=X2;
            app.Y2=Y2;
            app.Z2=Z2;
            plot(app.X1/app.dd,app.ly-app.Y1/app.dd,'g.',app.X2/app.dd,app.ly-app.Y2/app.dd,'r.','Parent',app.UIAxes_2)
            ax2=app.UIAxes_2;
            linkaxes([ax1 ax2],'xy');
            ax2.XLim=[0 app.lx];
            ax2.YLim=[0 app.ly];
            ax2.YDir='reverse';
            app.MsgEditField.Value='OK!';
%             plot(xt,yt,'w+','LineWidth',2,'Parent',app.UIAxes)
%             app.xt=xt;app.yt=yt;
        end

        % Button pushed function: FinishselectButton
        function FinishselectButtonPushed(app, event)
            app.MsgEditField.Value='Saving selected data...';
            assignin('base','Synapse',app.Synapse)
            assignin('base','xt',app.xt)
            assignin('base','yt',app.yt)
            Synapse=app.Synapse;
            xt=app.xt;
            yt=app.yt;
            save([app.fname,'-SelectedSynapse.mat'],'Synapse','xt','yt')
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: SelectSynapseButton
        function SelectSynapseButtonPushed(app, event)
            app.MsgEditField.Value='Click to select';
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)
            [xtt ytt]=ginput(1);
            app.NoS=app.NoS+1;
            app.xt(app.NoS)=xtt;
            app.yt(app.NoS)=ytt;
            app.ToS.Value = length(app.xt);
            app.NoS=app.ToS.Value;
            app.NoofSynapseSpinner.Value=app.NoS;
            hold(app.UIAxes,'on')
            plot(xtt,ytt,'b+','markersize',10,'LineWidth',2,'Parent',app.UIAxes)
            hold(app.UIAxes,'off')
            hold(app.UIAxes_2,'on')
            plot(xtt,ytt,'k+','markersize',10,'LineWidth',2,'Parent',app.UIAxes_2)
            hold(app.UIAxes_2,'off')
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: SelectClusterButton
        function SelectClusterButtonPushed(app, event)
            app.MsgEditField.Value='Click to select';
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)
            [xtt ytt]=ginput();
            xtt=xtt*app.dd;ytt=(app.ly-ytt)*app.dd;
            IN1=inpolygon(app.X1,app.Y1,xtt,ytt);
            x1t=app.X1(IN1);y1t=app.Y1(IN1);z1t=app.Z1(IN1);vol11=app.Vol11(IN1);
            IN2=inpolygon(app.X2,app.Y2,xtt,ytt);
            x2t=app.X2(IN2);y2t=app.Y2(IN2);z2t=app.Z2(IN2);vol21=app.Vol21(IN2);
%             x1t=[1,2];y1t=[1,2];z1t=[1,2];
%             x2t=[2,1];y2t=[2,1];z2t=[2,1];
            assignin('base','x2t',x2t);
            app.Synapse(app.NoS).coord1=[x1t,y1t,z1t];
            app.Synapse(app.NoS).Vol11=vol11;
            app.Synapse(app.NoS).coord2=[x2t,y2t,z2t];
            app.Synapse(app.NoS).Vol21=vol21;
            app.Synapse(app.NoS).ID1=find(IN1);
            app.Synapse(app.NoS).ID2=find(IN2);
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            xyz=app.Synapse(app.NoS).coord2;
            coeff=pca(xyz);
            view(app.UIAxes_SV,coeff(:,2));
            app.MsgEditField.Value='OK!';
%             plot(x1t,y1t,'g.',x2t,y2t,'r.','MarkerSize',10,'Parent',app.UIAxes_XY)
%             app.UIAxes_XY.XLim=[min(x1t)-100 max(x1t)+100];
%             app.UIAxes_XY.YLim=[min(y1t)-100 max(y1t)+100];
%             plot(x1t,z1t,'g.',x2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_XZ)
%             app.UIAxes_XZ.XLim=[min(x1t)-100 max(x1t)+100];
%             app.UIAxes_XZ.ZLim=[min(z1t)-100 max(z1t)+100];
%             plot(y1t,z1t,'g.',y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_YZ)
%             app.UIAxes_YZ.ZLim=[min(z1t)-100 max(z1t)+100];
%             app.UIAxes_YZ.YLim=[min(y1t)-100 max(y1t)+100];
%             plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
%             xyz=app.Synapse(app.NoS).coord2;
%             coeff=pca(xyz);
%             view(app.UIAxes_SV,coeff(:,1));
        end

        % Value changed function: NoofSynapseSpinner
        function NoofSynapseSpinnerValueChanged(app, event)
            app.NoS = app.NoofSynapseSpinner.Value;
        end

        % Button pushed function: SelectinthisviewButton
        function SelectinthisviewButtonPushed(app, event)
            app.MsgEditField.Value='Click to select';
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            assignin('base','xyz1',xyz1);
            axy=app.UIAxes_SV;
            [az,el] = view(axy);
            A = viewmtx(az,el);
            [m,n] = size(xyz1(:,1));
            x4d = [xyz1(:,1),xyz1(:,2),xyz1(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x21(:) = x2d(1,:);
            y21(:) = x2d(2,:);
            [m,n] = size(xyz2(:,1));
            x4d = [xyz2(:,1),xyz2(:,2),xyz2(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x22(:) = x2d(1,:);
            y22(:) = x2d(2,:);
            plot(x21,y21,'g.',x22,y22,'r.','MarkerSize',10,'Parent',app.UIAxes_XY)
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)
            [xtt ytt]=ginput();
            IN1=inpolygon(x21,y21,xtt,ytt);
            x1t=xyz1(IN1,1);y1t=xyz1(IN1,2);z1t=xyz1(IN1,3);
            app.Synapse(app.NoS).Vol11=app.Synapse(app.NoS).Vol11(IN1);
            IN2=inpolygon(x22,y22,xtt,ytt);
            x2t=xyz2(IN2,1);y2t=xyz2(IN2,2);z2t=xyz2(IN2,3);
            app.Synapse(app.NoS).Vol21=app.Synapse(app.NoS).Vol21(IN2);
            app.Synapse(app.NoS).coord1=[x1t,y1t,z1t];
            app.Synapse(app.NoS).coord2=[x2t,y2t,z2t];
            app.Synapse(app.NoS).ID1=app.Synapse(app.NoS).ID1(IN1);
            app.Synapse(app.NoS).ID2=app.Synapse(app.NoS).ID2(IN2);
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: DeleteLastButton
        function DeleteLastButtonPushed(app, event)
            app.Synapse(app.NoS)=[];
            app.xt(app.NoS)=[];
            app.yt(app.NoS)=[];
            app.NoS=app.NoS-1;
            app.NoofSynapseSpinner.Value=app.NoS;
            app.ToS.Value=app.ToS.Value-1;
        end

        % Button pushed function: LoadLastButton
        function LoadLastButtonPushed(app, event)
            app.MsgEditField.Value='Loading previous data...';
            [filename,pathname]=uigetfile('*.*','Select an file');
            load([pathname,filename])
            app.NoS=length(xt);
            app.NoofSynapseSpinner.Value=app.NoS;
            app.ToS.Value=app.NoS;
            app.Synapse=Synapse;
            app.xt=xt;
            app.yt=yt;
            hold(app.UIAxes,'on')
            plot(xt,yt,'b+','markersize',10,'LineWidth',2,'Parent',app.UIAxes)
            hold(app.UIAxes,'off')
            hold(app.UIAxes_2,'on')
            plot(xt,yt,'k+','markersize',10,'LineWidth',2,'Parent',app.UIAxes_2)
            hold(app.UIAxes_2,'off')
            app.MsgEditField.Value='OK!';
        end

        % Value changed function: DensityThresholdSlider
        function DensityThresholdSliderValueChanged(app, event)
            app.NoSP = app.DensityThresholdSlider.Value;
        end

        % Button pushed function: AutoSelectButton
        function AutoSelectButtonPushed(app, event)
            if app.InfiniteCheckBox.Value==0
                densityTh1=app.DensityThresholdSlider.Value;
            else
                densityTh1=inf;
            end
            if app.InfiniteCheckBox_2.Value==0
                densityTh2=app.DensityThresholdSlider_2.Value;
            else
                densityTh2=inf;
            end
            IN1=app.Synapse(app.NoS).Vol11<densityTh1*app.mVol1;
            IN2=app.Synapse(app.NoS).Vol21<densityTh2*app.mVol2;
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            x1t=xyz1(IN1,1);y1t=xyz1(IN1,2);z1t=xyz1(IN1,3);
            x2t=xyz2(IN2,1);y2t=xyz2(IN2,2);z2t=xyz2(IN2,3);
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            hold(app.UIAxes_SV,'on')
            x1t=xyz1(~IN1,1);y1t=xyz1(~IN1,2);z1t=xyz1(~IN1,3);
            x2t=xyz2(~IN2,1);y2t=xyz2(~IN2,2);z2t=xyz2(~IN2,3);
            plot3(x1t,y1t,z1t,'y.',x2t,y2t,z2t,'m.','MarkerSize',10,'Parent',app.UIAxes_SV)
            hold(app.UIAxes_SV,'off')
            app.MsgEditField.Value=num2str([num2str(densityTh1),';',num2str(densityTh2)]);
        end

        % Button pushed function: UseautoResultsButton
        function UseautoResultsButtonPushed(app, event)
            densityTh1=app.DensityThresholdSlider.Value;
            densityTh2=app.DensityThresholdSlider_2.Value;
            IN1=app.Synapse(app.NoS).Vol11<densityTh1*app.mVol1;
            IN2=app.Synapse(app.NoS).Vol21<densityTh1*app.mVol2;
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            x1t=xyz1(IN1,1);y1t=xyz1(IN1,2);z1t=xyz1(IN1,3);
            x2t=xyz2(IN2,1);y2t=xyz2(IN2,2);z2t=xyz2(IN2,3);
            app.Synapse(app.NoS).coord1=[x1t,y1t,z1t];
            app.Synapse(app.NoS).coord2=[x2t,y2t,z2t];
            app.Synapse(app.NoS).Vol11=app.Synapse(app.NoS).Vol11(IN1);
            app.Synapse(app.NoS).Vol21=app.Synapse(app.NoS).Vol21(IN2);
            app.Synapse(app.NoS).ID1=app.Synapse(app.NoS).ID1(IN1);
            app.Synapse(app.NoS).ID2=app.Synapse(app.NoS).ID2(IN2);
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            app.MsgEditField.Value=num2str([num2str(densityTh1),';',num2str(densityTh2)]);
        end

        % Button pushed function: CheckthisButton
        function CheckthisButtonPushed(app, event)
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            x1t=xyz1(:,1);y1t=xyz1(:,2);z1t=xyz1(:,3);
            x2t=xyz2(:,1);y2t=xyz2(:,2);z2t=xyz2(:,3);
%             ax1=app.UIAxes;
%             ax1.XLim=[app.xt(app.NoS)-15 app.xt(app.NoS)+15];
%             ax1.YLim=[app.yt(app.NoS)-15 app.yt(app.NoS)+15];
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
        end

        % Value changed function: NoofdisplayedpointsEditField
        function NoofdisplayedpointsEditFieldValueChanged(app, event)
            app.NoSP = app.NoofdisplayedpointsEditField.Value;
            if isempty(app.X1)
                app.MsgEditField.Value='Load image first';
            else
                if app.NoSP==0
                    plot(app.X1/app.dd,app.ly-app.Y1/app.dd,'g.',app.X2/app.dd,app.ly-app.Y2/app.dd,'r.','Parent',app.UIAxes_2)
                else
                    if app.NoSP>length(app.X1)
                        IN1=logical(ones(size(app.X1)));
                    else
                        IN1=randperm(length(app.X1),app.NoSP);
                    end
                    if app.NoSP>length(app.X2)
                        IN2=logical(ones(size(app.X2)));
                    else
                        IN2=randperm(length(app.X2),app.NoSP);
                    end
                    plot(app.X1(IN1)/app.dd,app.ly-app.Y1(IN1)/app.dd,'g.',app.X2(IN2)/app.dd,app.ly-app.Y2(IN2)/app.dd,'r.','Parent',app.UIAxes_2)
                end
            end
        end

        % Button pushed function: CheckSelectedButton
        function CheckSelectedButtonPushed(app, event)
            app.MsgEditField.Value='Loading previous data...';
            [filename,pathname]=uigetfile('*.*','Select an file');
            load([pathname,filename])
            app.NoS=length(Synapse);
            app.NoofSynapseSpinner.Value=app.NoS;
            app.ToS.Value=app.NoS;
            app.Synapse=Synapse;
            app.xt=xt;
            app.yt=yt;
            app.fname=filename(1:end-20);
%             ax1=app.UIAxes;
%             ax2=app.UIAxes_2;
%             linkaxes([ax1 ax2],'xy');
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: FitaxialButton
        function FitaxialButtonPushed(app, event)
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            x1t=xyz1(:,1);y1t=xyz1(:,2);z1t=xyz1(:,3);
            x2t=xyz2(:,1);y2t=xyz2(:,2);z2t=xyz2(:,3);
            plot3(x1t,y1t,z1t,'g.',x2t,y2t,z2t,'r.','MarkerSize',10,'Parent',app.UIAxes_SV)
            xyz=[xyz1;xyz2];
            %two channels align
            [coeff,scores]=pca(xyz);
            view(app.UIAxes_SV,coeff(:,app.angelSpinner.Value));
            xyzt=scores(:,app.angelSpinner.Value);
            mint=min(xyzt);maxt=max(xyzt);
            xyz1t=scores(1:length(x1t),app.angelSpinner.Value);
            xyz2t=scores((length(x1t)+1:end),app.angelSpinner.Value);
            sp=round(app.SmoothspanSlider.Value);
            [n1 c1]=hist(xyz1t,mint:5:maxt);
            [n2 c2]=hist(xyz2t,mint:5:maxt);
            if sp==0
                sn1=n1;sn2=n2;
            else
                sn1=smooth(n1,sp);sn2=smooth(n2,sp);
            end
            [pks1,locs1] = findpeaks(sn1);
            [M,IN1]=maxk(pks1,app.PeakcountSpinner.Value);
            [pks2,locs2] = findpeaks(sn2);
            [M,IN2]=maxk(pks2,app.PeakcountSpinner.Value);
            pkds=inf;
            for i=1:app.PeakcountSpinner.Value
                c2t=c2(locs2(IN2));
                pkds=min(pkds,min(abs(c2t(i)-c1(locs1(IN1)))));
            end
            plot(c1,sn1/sum(n1),'g-',c1(locs1(IN1)),pks1(IN1)/sum(n1),'go',c2,sn2/sum(n2),'r-',c2(locs2(IN2)),pks2(IN2)/sum(n2),'ro','Parent',app.UIAxes_XY)
            axis(app.UIAxes_XY, 'tight')
            axis(app.UIAxes_XY, 'square')
            %channel one as ref
            [coeff1,scores11]=pca(xyz1);
            scores12=(xyz2-mean(xyz1))*coeff1;
            xyz1t=scores11(:,app.angelSpinner.Value);
            xyz2t=scores12(:,app.angelSpinner.Value);
            xyzt=[xyz1t;xyz2t];
            mint=min(xyzt);maxt=max(xyzt);
            [n1 c1]=hist(xyz1t,mint:5:maxt);
            [n2 c2]=hist(xyz2t,mint:5:maxt);
            if sp==0
                sn1=n1;sn2=n2;
            else
                sn1=smooth(n1,sp);sn2=smooth(n2,sp);
            end
            [pks1,locs1] = findpeaks(sn1);
            [M,IN1]=maxk(pks1,app.PeakcountSpinner.Value);
            [pks2,locs2] = findpeaks(sn2);
            [M,IN2]=maxk(pks2,app.PeakcountSpinner.Value);
            pkds1=inf;
            for i=1:app.PeakcountSpinner.Value
                c2t=c2(locs2(IN2));
                pkds1=min(pkds1,min(abs(c2t(i)-c1(locs1(IN1)))));
            end
%             plot(c1,sn1/sum(n1),'g-',c1(locs1(IN1)),pks1(IN1)/sum(n1),'go',c2,sn2/sum(n2),'r-',c2(locs2(IN2)),pks2(IN2)/sum(n2),'ro','Parent',app.UIAxes)
            %channel two as ref
            [coeff2,scores22]=pca(xyz2);
            scores21=(xyz1-mean(xyz2))*coeff2;
            xyz1t=scores21(:,app.angelSpinner.Value);
            xyz2t=scores22(:,app.angelSpinner.Value);
            xyzt=[xyz1t;xyz2t];
            mint=min(xyzt);maxt=max(xyzt);
            [n1 c1]=hist(xyz1t,mint:5:maxt);
            [n2 c2]=hist(xyz2t,mint:5:maxt);
            if sp==0
                sn1=n1;sn2=n2;
            else
                sn1=smooth(n1,sp);sn2=smooth(n2,sp);
            end
            [pks1,locs1] = findpeaks(sn1);
            [M,IN1]=maxk(pks1,app.PeakcountSpinner.Value);
            [pks2,locs2] = findpeaks(sn2);
            [M,IN2]=maxk(pks2,app.PeakcountSpinner.Value);
            pkds2=inf;
            for i=1:app.PeakcountSpinner.Value
                c2t=c2(locs2(IN2));
                pkds2=min(pkds2,min(abs(c2t(i)-c1(locs1(IN1)))));
            end
%             plot(c1,sn1/sum(n1),'g-',c1(locs1(IN1)),pks1(IN1)/sum(n1),'go',c2,sn2/sum(n2),'r-',c2(locs2(IN2)),pks2(IN2)/sum(n2),'ro','Parent',app.UIAxes_2)
            app.pkds=pkds;
            app.pkds1=pkds1;
            app.pkds2=pkds2;
            app.MsgEditField.Value=[num2str(pkds1),';',num2str(pkds2),';',num2str(pkds)];
        end

        % Button pushed function: OutputdistanceButton
        function OutputdistanceButtonPushed(app, event)
            app.MsgEditField.Value='Saving selected data...';
            assignin('base','Synapse',app.Synapse)
            assignin('base','xt',app.xt)
            assignin('base','yt',app.yt)
            Synapse=app.Synapse;
            xt=app.xt;
            yt=app.yt;
            save([app.fname,'-SelectedSynapse.mat'],'Synapse','xt','yt')
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: SaveDisButton
        function SaveDisButtonPushed(app, event)
            app.Synapse(app.NoS).pkds=app.pkds;
            app.Synapse(app.NoS).pkds1=app.pkds1;
            app.Synapse(app.NoS).pkds2=app.pkds2;
            app.pkds=[];
            app.pkds1=[];
            app.pkds2=[];
        end

        % Button pushed function: DrawinthisviewButton
        function DrawinthisviewButtonPushed(app, event)
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            axy=app.UIAxes_SV;
            [az,el] = view(axy);
            A = viewmtx(az,el);
            [m,n] = size(xyz1(:,1));
            x4d = [xyz1(:,1),xyz1(:,2),xyz1(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x21(:) = x2d(1,:);
            y21(:) = x2d(2,:);
            [m,n] = size(xyz2(:,1));
            x4d = [xyz2(:,1),xyz2(:,2),xyz2(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x22(:) = x2d(1,:);
            y22(:) = x2d(2,:);
            plot(x21,y21,'g.',x22,y22,'r.','MarkerSize',10,'Parent',app.UIAxes_XY)
            axis(app.UIAxes_XY, 'equal')
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)
            xx=1:1000;
            yy=1:1000;
            [xx yy]=meshgrid(xx,yy);
            xyz1=app.Synapse(app.NoS).coord1;
            xyz2=app.Synapse(app.NoS).coord2;
            axy=app.UIAxes_SV;
            [az,el] = view(axy);
            A = viewmtx(az,el);
            [m,n] = size(xyz1(:,1));
            x4d = [xyz1(:,1),xyz1(:,2),xyz1(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x21(:) = x2d(1,:);
            y21(:) = x2d(2,:);
            [m,n] = size(xyz2(:,1));
            x4d = [xyz2(:,1),xyz2(:,2),xyz2(:,3),ones(m*n,1)]';
            x2d = A*x4d;
            x2 = zeros(m,n); y2 = zeros(m,n);
            x22(:) = x2d(1,:);
            y22(:) = x2d(2,:);
            app.MsgEditField.Value='Rendering...';
            xy1=[x21',y21'];xy2=[x22',y22'];
            assignin('base','xy1',xy1)
            dx1=max(xy1(:,1))-min(xy1(:,1));
            dy1=max(xy1(:,2))-min(xy1(:,2));
            dx2=max(xy2(:,1))-min(xy2(:,1));
            dy2=max(xy2(:,2))-min(xy2(:,2));
            minx=min([xy1(:,1);xy2(:,1)]);
            miny=min([xy1(:,2);xy2(:,2)]);
            cx=mean([xy1(:,1);xy2(:,1)]);
            cy=mean([xy1(:,2);xy2(:,2)]);
            Pre1=20*ones(length(xy1),1);
            I1=zeros(1000,1000);I2=I1;
            for j=1:length(xy1)
%                 Pre1(j)=mean(data.data(data.data(:,5)==xy1(j,1)&data.data(:,6)==xy1(j,2),8));
                xt1=xy1(j,1)-cx+500;
                yt1=xy1(j,2)-cy+500;
                I1=I1+reshape(mvnpdf([xx(:),yy(:)],[xt1,yt1],[Pre1(j),Pre1(j)]),size(xx));
            end
            Pre2=20*ones(length(xy2),1);
            for j=1:length(xy2)
%                 Pre2(j)=mean(data.data(data.data(:,5)==xy2(j,1)&data.data(:,6)==xy2(j,2),7));
                xt2=xy2(j,1)-cx+500;
                yt2=xy2(j,2)-cy+500;
                I2=I2+reshape(mvnpdf([xx(:),yy(:)],[xt2,yt2],[Pre2(j),Pre2(j)]),size(xx));
            end
            mI=max([max(max(I1)),max(max(I2))]);
            I1=uint16((I1/mI)*(2^16-1));
            I2=uint16((I2/mI)*(2^16-1));
            I=uint16(zeros(1000,1000,3));
            I(:,:,1)=I2;I(:,:,2)=I1;
            I=flipud(I);
            imwrite(I,[app.fname,'-',num2str(app.NoS),'.tif'])
            app.MsgEditField.Value='OK!';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Create a temporary figure with axes.
            fig = figure;
            figAxes = axes(fig);
            % Copy all UIAxes children, take over axes limits and aspect ratio.
            allChildren = app.UIAxes_XY.XAxis.Parent.Children;
            copyobj(allChildren, figAxes)
            figAxes.XLim = app.UIAxes_XY.XLim;
            figAxes.YLim = app.UIAxes_XY.YLim;
            figAxes.ZLim = app.UIAxes_XY.ZLim;
            figAxes.DataAspectRatio = app.UIAxes_XY.DataAspectRatio;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_SV = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_SV, 'X')
            ylabel(app.UIAxes_SV, 'Y')
            zlabel(app.UIAxes_SV, 'Z')
            app.UIAxes_XY = uiaxes(app.UIFigure);
            zlabel(app.UIAxes_XY, 'Z')
            app.LoadIamgeButton = uibutton(app.UIFigure, 'push');
            app.LoadIamgeButton.ButtonPushedFcn = createCallbackFcn(app, @loaddata, true);
            app.LoadIamgeButton.Text = 'Load Iamge';
            app.FinishselectButton = uibutton(app.UIFigure, 'push');
            app.FinishselectButton.ButtonPushedFcn = createCallbackFcn(app, @FinishselectButtonPushed, true);
            app.FinishselectButton.Text = 'Finish select';
            app.SelectSynapseButton = uibutton(app.UIFigure, 'push');
            app.SelectSynapseButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSynapseButtonPushed, true);
            app.SelectSynapseButton.Text = 'Select Synapse';
            app.DeleteLastButton = uibutton(app.UIFigure, 'push');
            app.DeleteLastButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLastButtonPushed, true);
            app.DeleteLastButton.Text = 'Delete current';
            app.SelectClusterButton = uibutton(app.UIFigure, 'push');
            app.SelectClusterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClusterButtonPushed, true);
            app.SelectClusterButton.Text = 'Select Cluster';
            app.NoofSynapseSpinnerLabel = uilabel(app.UIFigure);
            app.NoofSynapseSpinnerLabel.Text = 'No. of Synapse';
            app.NoofSynapseSpinner = uispinner(app.UIFigure);
            app.NoofSynapseSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofSynapseSpinnerValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Total No. Synpase';
            app.ToS = uieditfield(app.UIFigure, 'numeric');
            app.ToS.Editable = 'off';
            app.MsgLabel = uilabel(app.UIFigure);
            app.MsgLabel.Text = 'Msg:';
            app.MsgEditField = uieditfield(app.UIFigure, 'text');
            app.MsgEditField.Editable = 'off';
            app.MsgEditField.Value = 'Load a WF image';
            app.SelectinthisviewButton = uibutton(app.UIFigure, 'push');
            app.SelectinthisviewButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinthisviewButtonPushed, true);
            app.SelectinthisviewButton.Text = {'Select in'; 'this view'};
            app.LoadLastButton = uibutton(app.UIFigure, 'push');
            app.LoadLastButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLastButtonPushed, true);
            app.LoadLastButton.Text = 'Load last';
            app.DensityThresholdSliderLabel = uilabel(app.UIFigure);
            app.DensityThresholdSliderLabel.Text = {'Density'; 'Threshold'};
            app.DensityThresholdSlider = uislider(app.UIFigure);
            app.DensityThresholdSlider.Limits = [0 5];
            app.DensityThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @DensityThresholdSliderValueChanged, true);
            app.DensityThresholdSlider.Value = 1;
            app.AutoSelectButton = uibutton(app.UIFigure, 'push');
            app.AutoSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSelectButtonPushed, true);
            app.AutoSelectButton.Text = 'Auto Select';
            app.UseautoResultsButton = uibutton(app.UIFigure, 'push');
            app.UseautoResultsButton.ButtonPushedFcn = createCallbackFcn(app, @UseautoResultsButtonPushed, true);
            app.UseautoResultsButton.Text = 'Use auto Results';
            app.DensityThresholdSlider_2Label = uilabel(app.UIFigure);
            app.DensityThresholdSlider_2Label.Text = {'Density'; 'Threshold'};
            app.DensityThresholdSlider_2 = uislider(app.UIFigure);
            app.DensityThresholdSlider_2.Limits = [0 5];
            app.DensityThresholdSlider_2.Value = 1;
            app.CheckthisButton = uibutton(app.UIFigure, 'push');
            app.CheckthisButton.ButtonPushedFcn = createCallbackFcn(app, @CheckthisButtonPushed, true);
            app.CheckthisButton.Text = 'Check this';
            app.InfiniteCheckBox = uicheckbox(app.UIFigure);
            app.InfiniteCheckBox.Text = 'Infinite';
            app.InfiniteCheckBox_2 = uicheckbox(app.UIFigure);
            app.InfiniteCheckBox_2.Text = 'Infinite';
            app.NoofdisplayedpointsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofdisplayedpointsEditFieldLabel.Text = 'No. of displayed points';
            app.NoofdisplayedpointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofdisplayedpointsEditField.ValueChangedFcn = createCallbackFcn(app, @NoofdisplayedpointsEditFieldValueChanged, true);
            app.CheckSelectedButton = uibutton(app.UIFigure, 'push');
            app.CheckSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSelectedButtonPushed, true);
            app.CheckSelectedButton.Text = 'Check Selected';
            app.FitaxialButton = uibutton(app.UIFigure, 'push');
            app.FitaxialButton.ButtonPushedFcn = createCallbackFcn(app, @FitaxialButtonPushed, true);
            app.FitaxialButton.Text = 'Fit axial';
            app.angelSpinnerLabel = uilabel(app.UIFigure);
            app.angelSpinnerLabel.Text = 'angel';
            app.angelSpinner = uispinner(app.UIFigure);
            app.angelSpinner.Value = 3;
            app.SmoothspanLabel = uilabel(app.UIFigure);
            app.SmoothspanLabel.Text = {'Smooth'; 'span'};
            app.SmoothspanSlider = uislider(app.UIFigure);
            app.SmoothspanSlider.Limits = [0 20];
            app.SmoothspanSlider.MajorTicks = [0 5 10 15 20];
            app.SmoothspanSlider.Value = 5;
            app.PeakcountSpinnerLabel = uilabel(app.UIFigure);
            app.PeakcountSpinnerLabel.Text = {'Peak'; 'count'};
            app.PeakcountSpinner = uispinner(app.UIFigure);
            app.PeakcountSpinner.Value = 1;
            app.OutputdistanceButton = uibutton(app.UIFigure, 'push');
            app.OutputdistanceButton.ButtonPushedFcn = createCallbackFcn(app, @OutputdistanceButtonPushed, true);
            app.OutputdistanceButton.Text = 'Output distance';
            app.SaveDisButton = uibutton(app.UIFigure, 'push');
            app.SaveDisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDisButtonPushed, true);
            app.SaveDisButton.Text = 'Save Dis';
            app.DrawinthisviewButton = uibutton(app.UIFigure, 'push');
            app.DrawinthisviewButton.ButtonPushedFcn = createCallbackFcn(app, @DrawinthisviewButtonPushed, true);
            app.DrawinthisviewButton.Text = {'Draw in'; 'this view'};
            app.RenderButton = uibutton(app.UIFigure, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STORMprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end